classdef Multi_controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SendValuesButton               matlab.ui.control.Button
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        SystemSwitchLabel              matlab.ui.control.Label
        SystemSwitch                   matlab.ui.control.Switch
        LED1SwitchLabel                matlab.ui.control.Label
        LED1Switch                     matlab.ui.control.ToggleSwitch
        LED2SwitchLabel                matlab.ui.control.Label
        LED2Switch                     matlab.ui.control.ToggleSwitch
        LED3SwitchLabel                matlab.ui.control.Label
        LED3Switch                     matlab.ui.control.ToggleSwitch
        LED4SwitchLabel                matlab.ui.control.Label
        LED4Switch                     matlab.ui.control.ToggleSwitch
        LED5SwitchLabel                matlab.ui.control.Label
        LED5Switch                     matlab.ui.control.ToggleSwitch
        LED6SwitchLabel                matlab.ui.control.Label
        LED6Switch                     matlab.ui.control.ToggleSwitch
        PowerEditFieldLabel            matlab.ui.control.Label
        PowerEditField                 matlab.ui.control.NumericEditField
        OntimemsEditFieldLabel         matlab.ui.control.Label
        OntimemsEditField              matlab.ui.control.NumericEditField
        OfftimemsEditFieldLabel        matlab.ui.control.Label
        OfftimemsEditField             matlab.ui.control.NumericEditField
        RepetitionTimesEditFieldLabel  matlab.ui.control.Label
        RepetitionTimesEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        s; % Description
        %"Enter: power,led,state,pulse_width,Turn_on"
        power=0;
        led=0;
        state=0;
        pulse_width_on=0;
        pulse_width_off=0;
        system=0;
        repTimes=0;
        sent_value;  
        
    end

    methods (Access = private)
    
        function update_serial(app)
            app.sent_value=strcat('xx',',',int2str(app.power),',',...
                int2str(app.system),',',...
                int2str(app.led),',',int2str(app.state),',',...
                int2str(app.pulse_width_on),',',...
                int2str(app.pulse_width_off),',',int2str(app.repTimes));
            fprintf(app.s,'%s',app.sent_value);
            app.EditField.Value = app.sent_value;
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            app.s=serial('COM6','BAUD', 9600);  
            fopen(app.s);
        end

        % Callback function
        function DeviceSwitchValueChanged(app, event)

        end

        % Callback function
        function PowerSliderValueChanged(app, event)
            app.power = app.PowerSlider.Value;
        end

        % Button pushed function: SendValuesButton
        function SendValuesButtonPushed(app, event)
            update_serial(app);
        end

        % Callback function
        function OntimemsSliderValueChanged(app, event)
            app.pulse_width_on = app.OntimemsSlider.Value;
        end

        % Value changed function: SystemSwitch
        function SystemSwitchValueChanged2(app, event)
            switch app.SystemSwitch.Value
                case 'On'
                    app.system=1;
                case 'Off'
                    app.system=0;
            end
            update_serial(app);
        end

        % Value changed function: LED1Switch
        function LED1SwitchValueChanged(app, event)
            app.led=1;
            switch app.LED1Switch.Value
                case 'On'
                    app.state=1;              
                case 'Off'
                    app.state=0;
            end
            update_serial(app);
        end

        % Value changed function: LED2Switch
        function LED2SwitchValueChanged(app, event)
            app.led=2;
            switch app.LED2Switch.Value
                case 'On'
                    app.state=1;              
                case 'Off'
                    app.state=0;
            end
            update_serial(app);
        end

        % Value changed function: LED3Switch
        function LED3SwitchValueChanged(app, event)
            app.led=3;
            switch app.LED3Switch.Value
                case 'On'
                    app.state=1;              
                case 'Off'
                    app.state=0;
            end
            update_serial(app);
        end

        % Value changed function: LED4Switch
        function LED4SwitchValueChanged(app, event)
            app.led=4;
            switch app.LED4Switch.Value
                case 'On'
                    app.state=1;
                case 'Off'
                    app.state=0;
            end
            update_serial(app);
        end

        % Value changed function: LED5Switch
        function LED5SwitchValueChanged(app, event)
            app.led=5;
            switch app.LED5Switch.Value
                case 'On'
                    app.state=1;              
                case 'Off'
                    app.state=0;
            end
            update_serial(app);
        end

        % Value changed function: LED6Switch
        function LED6SwitchValueChanged(app, event)
            app.led=6;
            switch app.LED6Switch.Value
                case 'On'
                    app.state=1;              
                case 'Off'
                    app.state=0;
            end
            update_serial(app);
        end

        % Callback function
        function OfftimemsSliderValueChanged(app, event)
            app.pulse_width_off = app.OfftimemsSlider.Value;
            
        end

        % Value changed function: PowerEditField
        function PowerEditFieldValueChanged(app, event)
            app.power = app.PowerEditField.Value;
            
        end

        % Value changed function: OntimemsEditField
        function OntimemsEditFieldValueChanged(app, event)
            app.pulse_width_on = app.OntimemsEditField.Value;
            
        end

        % Value changed function: OfftimemsEditField
        function OfftimemsEditFieldValueChanged(app, event)
            app.pulse_width_off = app.OfftimemsEditField.Value;
            
        end

        % Value changed function: RepetitionTimesEditField
        function RepetitionTimesEditFieldValueChanged(app, event)
            app.repTimes = app.RepetitionTimesEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SendValuesButton = uibutton(app.UIFigure, 'push');
            app.SendValuesButton.ButtonPushedFcn = createCallbackFcn(app, @SendValuesButtonPushed, true);
            app.SendValuesButton.Text = 'Send Values';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.SystemSwitchLabel = uilabel(app.UIFigure);
            app.SystemSwitchLabel.Text = 'System';
            app.SystemSwitch = uiswitch(app.UIFigure, 'slider');
            app.SystemSwitch.ValueChangedFcn = createCallbackFcn(app, @SystemSwitchValueChanged2, true);
            app.LED1SwitchLabel = uilabel(app.UIFigure);
            app.LED1SwitchLabel.Text = 'LED 1';
            app.LED1Switch = uiswitch(app.UIFigure, 'toggle');
            app.LED1Switch.ValueChangedFcn = createCallbackFcn(app, @LED1SwitchValueChanged, true);
            app.LED2SwitchLabel = uilabel(app.UIFigure);
            app.LED2SwitchLabel.Text = 'LED 2';
            app.LED2Switch = uiswitch(app.UIFigure, 'toggle');
            app.LED2Switch.ValueChangedFcn = createCallbackFcn(app, @LED2SwitchValueChanged, true);
            app.LED3SwitchLabel = uilabel(app.UIFigure);
            app.LED3SwitchLabel.Text = 'LED 3';
            app.LED3Switch = uiswitch(app.UIFigure, 'toggle');
            app.LED3Switch.ValueChangedFcn = createCallbackFcn(app, @LED3SwitchValueChanged, true);
            app.LED4SwitchLabel = uilabel(app.UIFigure);
            app.LED4SwitchLabel.Text = 'LED 4';
            app.LED4Switch = uiswitch(app.UIFigure, 'toggle');
            app.LED4Switch.ValueChangedFcn = createCallbackFcn(app, @LED4SwitchValueChanged, true);
            app.LED5SwitchLabel = uilabel(app.UIFigure);
            app.LED5SwitchLabel.Text = 'LED 5';
            app.LED5Switch = uiswitch(app.UIFigure, 'toggle');
            app.LED5Switch.ValueChangedFcn = createCallbackFcn(app, @LED5SwitchValueChanged, true);
            app.LED6SwitchLabel = uilabel(app.UIFigure);
            app.LED6SwitchLabel.Text = 'LED 6';
            app.LED6Switch = uiswitch(app.UIFigure, 'toggle');
            app.LED6Switch.ValueChangedFcn = createCallbackFcn(app, @LED6SwitchValueChanged, true);
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerEditField.Limits = [0 100];
            app.PowerEditField.ValueChangedFcn = createCallbackFcn(app, @PowerEditFieldValueChanged, true);
            app.OntimemsEditFieldLabel = uilabel(app.UIFigure);
            app.OntimemsEditFieldLabel.Text = 'On time (ms)';
            app.OntimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OntimemsEditField.Limits = [0 Inf];
            app.OntimemsEditField.ValueChangedFcn = createCallbackFcn(app, @OntimemsEditFieldValueChanged, true);
            app.OfftimemsEditFieldLabel = uilabel(app.UIFigure);
            app.OfftimemsEditFieldLabel.Text = 'Off time (ms)';
            app.OfftimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OfftimemsEditField.Limits = [0 Inf];
            app.OfftimemsEditField.ValueChangedFcn = createCallbackFcn(app, @OfftimemsEditFieldValueChanged, true);
            app.RepetitionTimesEditFieldLabel = uilabel(app.UIFigure);
            app.RepetitionTimesEditFieldLabel.Text = 'Repetition Times';
            app.RepetitionTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.RepetitionTimesEditField.ValueChangedFcn = createCallbackFcn(app, @RepetitionTimesEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Multi_controller

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end