classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PowerLabel               matlab.ui.control.Label
        PowerSlider              matlab.ui.control.Slider
        PulseWidthmsSliderLabel  matlab.ui.control.Label
        PulseWidthmsSlider       matlab.ui.control.Slider
        LEDSwitchLabel           matlab.ui.control.Label
        LEDSwitch                matlab.ui.control.Switch
        SendValuesButton         matlab.ui.control.Button
        EditFieldLabel           matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        SystemSwitchLabel        matlab.ui.control.Label
        SystemSwitch             matlab.ui.control.Switch
    end


    properties (Access = public)
        s; % Description
        %"Enter: power,led,state,pulse_width,Turn_on"
        power=0;
        led=1;
        state=0;
        pulse_width=0;
        system=0;
        sent_value;        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            app.s=serial('COM6','BAUD', 9600);  
            fopen(app.s);
        end

        % Value changed function: LEDSwitch
        function LEDSwitchValueChanged(app, event)
            switch app.LEDSwitch.Value
                case 'On'
                    app.state=1;              
                case 'Off'
                    app.state=0;
            end
            app.sent_value=strcat(int2str(app.power),',',...
                int2str(app.led),',',int2str(app.state),',',...
                int2str(app.pulse_width),',',int2str(app.system));
            fprintf(app.s,'%s',app.sent_value);
            app.EditField.Value = app.sent_value;
        end

        % Callback function
        function DeviceSwitchValueChanged(app, event)

        end

        % Value changed function: PowerSlider
        function PowerSliderValueChanged(app, event)
            app.power = app.PowerSlider.Value;
        end

        % Button pushed function: SendValuesButton
        function SendValuesButtonPushed(app, event)
            app.sent_value=strcat(int2str(app.power),',',...
                int2str(app.led),',',int2str(app.state),',',...
                int2str(app.pulse_width),',',int2str(app.system));
            fprintf(app.s,'%s',app.sent_value);
            app.EditField.Value = app.sent_value;
            
        end

        % Value changed function: PulseWidthmsSlider
        function PulseWidthmsSliderValueChanged(app, event)
            app.pulse_width = app.PulseWidthmsSlider.Value;
        end

        % Value changed function: SystemSwitch
        function SystemSwitchValueChanged2(app, event)
            switch app.SystemSwitch.Value
                case 'On'
                    app.system=1;
                case 'Off'
                    app.system=0;
            end
            app.state=0;
            app.sent_value=strcat(int2str(app.power),',',...
                int2str(app.led),',',int2str(app.state),',',...
                int2str(app.pulse_width),',',int2str(app.system));
            fprintf(app.s,'%s',app.sent_value);
            app.EditField.Value = app.sent_value; 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PowerLabel = uilabel(app.UIFigure);
            app.PowerLabel.Text = 'Power (%)';
            app.PowerSlider = uislider(app.UIFigure);
            app.PowerSlider.ValueChangedFcn = createCallbackFcn(app, @PowerSliderValueChanged, true);
            app.PulseWidthmsSliderLabel = uilabel(app.UIFigure);
            app.PulseWidthmsSliderLabel.Text = 'Pulse Width (ms)';
            app.PulseWidthmsSlider = uislider(app.UIFigure);
            app.PulseWidthmsSlider.ValueChangedFcn = createCallbackFcn(app, @PulseWidthmsSliderValueChanged, true);
            app.LEDSwitchLabel = uilabel(app.UIFigure);
            app.LEDSwitchLabel.Text = 'LED';
            app.LEDSwitch = uiswitch(app.UIFigure, 'slider');
            app.LEDSwitch.ValueChangedFcn = createCallbackFcn(app, @LEDSwitchValueChanged, true);
            app.SendValuesButton = uibutton(app.UIFigure, 'push');
            app.SendValuesButton.ButtonPushedFcn = createCallbackFcn(app, @SendValuesButtonPushed, true);
            app.SendValuesButton.Text = 'Send Values';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.SystemSwitchLabel = uilabel(app.UIFigure);
            app.SystemSwitchLabel.Text = 'System';
            app.SystemSwitch = uiswitch(app.UIFigure, 'slider');
            app.SystemSwitch.ValueChangedFcn = createCallbackFcn(app, @SystemSwitchValueChanged2, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end